classdef retinaDetection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        LoadrawimageButton         matlab.ui.control.Button
        LoadpatternButton          matlab.ui.control.Button
        CompareimagesButton        matlab.ui.control.Button
        SavedetectedpatternButton  matlab.ui.control.Button
        Label_compare              matlab.ui.control.Label
        Label_save                 matlab.ui.control.Label
        ShowcomparisoninnewwindowButton  matlab.ui.control.Button
        Label                      matlab.ui.control.Label
    end

    
    properties (Access = private) 
        main_figure % handler to main figure
        zoom_figure % figure in which zoomed comparison will be shown
        ah1; ah2; ah3; ah4; ah5; % axes handlers in main figure
        ax; % axes in zoom
        pattern % loaded pattern (class object)
        raw_image % loaded raw image (class object)
        comparison % comparison result (structure)
    end
    
    methods (Access = private)
        
        function create_figure(app) %function to create figure and all of its axeses
            % deleting previous axes while creating new one
            if ~isempty(app.main_figure)
                delete(app.main_figure)
            end
            
            % setting positions
            app.main_figure = uifigure('Name','Results');
            app.main_figure.Position = [550, 150, 650, 500];
            app.ah1 = axes('Parent',app.main_figure,'Units','normalized','Position',[0.1 0.6 0.3 0.3]);
            app.ah2 = axes('Parent',app.main_figure,'Units','normalized','Position',[0.1 0.2 0.3 0.3]);
            app.ah3 = axes('Parent',app.main_figure,'Units','normalized','Position',[0.5 0.6 0.3 0.3]);
            app.ah4 = axes('Parent',app.main_figure,'Units','normalized','Position',[0.5 0.2 0.4 0.3]);
            app.ah5 = axes('Parent',app.main_figure,'Units','normalized','Position',[0.5 0.6 0.4 0.3]);
            
            % raw image section
            text(app.ah1, 0.1, 0.5,'Load the raw image'); axis(app.ah1, 'off') 
            set(app.ah1,'visible', 'off');
            set(app.ah3,'visible', 'off');
            
            % pattern section
            text(app.ah2, 0.1, 0.5,'Load the pattern'); axis(app.ah2, 'off') 
            set(app.ah2,'visible', 'off');
            
            % comparison section
            text(app.ah4, 0.5, 0.5, 'Compare images'); axis(app.ah3, 'off') 
            set(app.ah4, 'visible', 'off');
            set(app.ah5, 'visible', 'off');

        end
        
        function show_raw(app)
            % delete previous comparison
            app.clear_comparison; 
            
            imagesc(app.ah1, app.raw_image.resized_image); 
            set(app.ah1, 'Units', 'pixels', 'Position', [50, 300, 141.25, 146]);
            title(app.ah1, 'raw image')
            axis(app.ah1,'off')
            
            imagesc(app.ah3, app.raw_image.processed_image);
            colormap(app.ah3, 'gray');
            set(app.ah3, 'Units', 'pixels', 'Position', [250, 300, 141.25, 146]);
            title(app.ah3, 'detected pattern')
            axis(app.ah3,'off')

        end
        
        function show_pattern(app)
            % delete previous comparison
            app.clear_comparison;
            
            imagesc(app.ah2, app.pattern.processed_image);
            colormap(app.ah2, 'gray');
            set(app.ah2, 'Units', 'pixels', 'Position', [50, 70, 141.25, 146]);
            title(app.ah2, 'loaded pattern')
            axis(app.ah2,'off')
        end
        
        function clear_comparison(app)
            delete(app.ah4)
            app.ah4 = axes('Parent',app.main_figure,'Units','normalized','Position',[0.5 0.2 0.4 0.3]);
            text(app.ah4, 0.5, 0.5, 'Compare images');
            set(app.ah4, 'visible', 'off');
            delete(app.zoom_figure) % deleting figure with zoom
            delete(app.ax) % deleting axes from zoom
            delete(app.ah5) %deleting axes with comparison
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.create_figure;
        end

        % Button pushed function: LoadrawimageButton
        function LoadrawimageButtonPushed(app, event)
            % function creating new window, if the previous was closed
            if ~isprop(app.main_figure, 'Position')
                app.create_figure;
                %showing closed image
                if ~isempty(app.pattern)
                    app.show_pattern;
                end
            end
            
            try
                app.raw_image = raw_eye_image;
                app.show_raw;
            catch error
                %app.warning_figure = uifigure;
                uialert(app.main_figure, error.message, 'error')
            end
            
        end

        % Button pushed function: SavedetectedpatternButton
        function SavedetectedpatternButtonPushed(app, event)
            if isempty(app.raw_image)
                app.Label_save.Text = 'first you must load raw image';
            else 
                try
                    app.Label_save.Text = '';
                    app.raw_image.savePattern;
                catch error
                    %app.warning_figure = uifigure;
                    uialert(app.main_figure, error.message, 'error')
                end
            end
        end

        % Button pushed function: LoadpatternButton
        function LoadpatternButtonPushed(app, event)
            % function creating new window, if the previous was closed
            if ~isprop(app.main_figure, 'Position')
                app.create_figure;
                % showing closed image
                if ~isempty(app.raw_image)
                    app.show_raw;
                end
            end
            
            try
                app.pattern = eye_pattern;
                app.pattern.get_final_eye_pattern;
                app.show_pattern;            
            catch error
                %app.warning_figure = uifigure;
                uialert(app.main_figure, error.message, 'error')
            end
        end

        % Button pushed function: CompareimagesButton
        function CompareimagesButtonPushed(app, event)
            if isempty(app.pattern) || isempty(app.raw_image)
                app.Label_compare.Text = 'first you must load two images';
            else
                app.Label.Text = '';
                app.Label_compare.Text = '';
                app.comparison = compare_two_images(app.pattern.processed_image, app.raw_image.processed_image);
                msg_ssim = ['SSIM value is ',num2str(app.comparison.SSIM)]; % 1 means identical
                msg_perc = [ num2str(app.comparison.percentage), '% of features are the same'];                
                
                set(app.ah4, 'Units', 'pixels', 'Position', [250, 70, 282.5, 146]);
                imshowpair(app.comparison.best_pattern, app.comparison.detected, 'montage', 'Parent', app.ah4);
                title(app.ah4, {msg_ssim, msg_perc})     
                
                app.ah5 = axes('Parent',app.main_figure,'Units','normalized','Position',[0.5 0.6 0.4 0.3]);
                set(app.ah5, 'visible', 'off');
                
                if app.comparison.percentage < 3
                     text(app.ah5, 0.3, 0.8, 'your image may be low quality ' )
                     text(app.ah5, 0.3, 0.7, 'or present object that is not an eye')
                end
                
                if (app.comparison.percentage > 20 && app.comparison.SSIM > 0.5)
                    text(app.ah5, 0.3, 0.5, 'eye from raw image belongs ' )
                    text(app.ah5, 0.3, 0.4, 'to the person from pattern')
                else
                    text(app.ah5, 0.3, 0.5, 'eye from raw image does not ' )
                    text(app.ah5, 0.3, 0.4, 'belong to the person from pattern')
                end
            end
        end

        % Button pushed function: ShowcomparisoninnewwindowButton
        function ShowcomparisoninnewwindowButtonPushed(app, event)
            if isempty(app.comparison)
                app.Label.Text = 'first you must make a comparison';
            else 
                app.zoom_figure = uifigure('Name', 'Zoomed comparison');
                app.ax = axes('Parent',app.zoom_figure);
                showMatchedFeatures(app.comparison.best_pattern, app.comparison.detected, app.comparison.matchedP, app.comparison.matchedD,'montage', 'Parent',app.ax);
                msg = ['Detected ', num2str(app.comparison.score), ' matched features'];
                title(app.ax, msg)
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % deleting all figure handlers
            delete(app.main_figure) 
            delete(app.zoom_figure);
            % deleting all axes handlers
            delete(app.ah1); delete(app.ah2); delete(app.ah3); 
            delete(app.ah4); delete(app.ah5); delete(app.ax); 
            % deleting app
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.LoadrawimageButton = uibutton(app.UIFigure, 'push');
            app.LoadrawimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadrawimageButtonPushed, true);
            app.LoadrawimageButton.Text = {'Load raw image'; ''};
            app.LoadpatternButton = uibutton(app.UIFigure, 'push');
            app.LoadpatternButton.ButtonPushedFcn = createCallbackFcn(app, @LoadpatternButtonPushed, true);
            app.LoadpatternButton.Text = 'Load pattern';
            app.CompareimagesButton = uibutton(app.UIFigure, 'push');
            app.CompareimagesButton.ButtonPushedFcn = createCallbackFcn(app, @CompareimagesButtonPushed, true);
            app.CompareimagesButton.Text = {'Compare images'; ''};
            app.SavedetectedpatternButton = uibutton(app.UIFigure, 'push');
            app.SavedetectedpatternButton.ButtonPushedFcn = createCallbackFcn(app, @SavedetectedpatternButtonPushed, true);
            app.SavedetectedpatternButton.Text = {'Save detected pattern'; ''};
            app.Label_compare = uilabel(app.UIFigure);
            app.Label_compare.Text = '';
            app.Label_save = uilabel(app.UIFigure);
            app.Label_save.Text = '';
            app.ShowcomparisoninnewwindowButton = uibutton(app.UIFigure, 'push');
            app.ShowcomparisoninnewwindowButton.ButtonPushedFcn = createCallbackFcn(app, @ShowcomparisoninnewwindowButtonPushed, true);
            app.ShowcomparisoninnewwindowButton.Text = 'Show comparison in new window';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = retinaDetection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end